classdef PID_Sim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        KdLabel   matlab.ui.control.Label
        KiLabel   matlab.ui.control.Label
        KpLabel   matlab.ui.control.Label
        KdSlider  matlab.ui.control.Slider
        KiSlider  matlab.ui.control.Slider
        KpSlider  matlab.ui.control.Slider
        UIAxes    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: KdSlider, KiSlider, KpSlider
        function KpSliderValueChanged(app, event)
            kp = app.KpSlider.Value;
            ki = app.KpSlider.Value;
            kd = app.KpSlider.Value;

            app.KpLabel.Text = sprintf('Kp: %.2f', kp);
            app.KiLabel.Text = sprintf('Ki: %.2f', ki);
            app.KdLabel.Text = sprintf('Kd: %.2f', kd);

            plant = tf(1, [1 10 20]);
            C = pid(kp, ki, kd);
            sys_cl = feedback(C * plant, 1);

            cla(app.UIAxes)
            step(app.UIAxes, sys_cl);
            title(app.UIAxes, 'PID');
            grid(app.UIAxes, 'on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'PID')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.KpSlider = uislider(app.UIFigure);
            app.KpSlider.MajorTicks = [];
            app.KpSlider.ValueChangedFcn = createCallbackFcn(app, @KpSliderValueChanged, true);
            app.KiSlider = uislider(app.UIFigure);
            app.KiSlider.MajorTicks = [];
            app.KiSlider.ValueChangedFcn = createCallbackFcn(app, @KpSliderValueChanged, true);
            app.KdSlider = uislider(app.UIFigure);
            app.KdSlider.MajorTicks = [];
            app.KdSlider.ValueChangedFcn = createCallbackFcn(app, @KpSliderValueChanged, true);
            app.KpLabel = uilabel(app.UIFigure);
            app.KpLabel.Text = 'Kp';
            app.KiLabel = uilabel(app.UIFigure);
            app.KiLabel.Text = 'Ki';
            app.KdLabel = uilabel(app.UIFigure);
            app.KdLabel.Text = 'Kd';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PID_Sim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end